classdef bmMriPhi_graphical_frequency_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bmMriPhi_graphical_frequency_selectorUIFigure  matlab.ui.Figure
        YaxisLabel            matlab.ui.control.Label
        XaxisLabel            matlab.ui.control.Label
        nu_maxLabel           matlab.ui.control.Label
        nu_minLabel           matlab.ui.control.Label
        PlotLabel             matlab.ui.control.Label
        FIXCONTRASTButton     matlab.ui.control.Button
        IMCONTRASTButton      matlab.ui.control.Button
        SHIFTDOWNButton       matlab.ui.control.Button
        deSTRETCH_DOWNButton  matlab.ui.control.Button
        STRETCH_DOWNButton    matlab.ui.control.Button
        SHIFTUPButton         matlab.ui.control.Button
        deSTRETCH_UPButton    matlab.ui.control.Button
        STRETCH_UPButton      matlab.ui.control.Button
        SHOWYELLOWButton      matlab.ui.control.Button
        HIDEYELLOWButton      matlab.ui.control.Button
        CENTERDOWNButton      matlab.ui.control.Button
        AMPDOWNButton         matlab.ui.control.Button
        CENTERUPButton        matlab.ui.control.Button
        AMPUPButton           matlab.ui.control.Button
        Button_34             matlab.ui.control.Button
        Button_21             matlab.ui.control.Button
        Button_20             matlab.ui.control.Button
        Button_35             matlab.ui.control.Button
        Button_19             matlab.ui.control.Button
        Button_18             matlab.ui.control.Button
        FilterSignalButton    matlab.ui.control.Button
        Button_16             matlab.ui.control.Button
        Button_15             matlab.ui.control.Button
        Button_14             matlab.ui.control.Button
        Button_13             matlab.ui.control.Button
        Button_12             matlab.ui.control.Button
        Button_11             matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes_1              matlab.ui.control.UIAxes
    end


% Bastien Milani
% Martigny 2026

    properties (Access = public)
        

        % input arguments
        s_ref                = [];
        t_ref                = [];
        Fs_ref               = [];
        nu_ref               = [];
        imNav                = [];

        % output arguments
        s_lowPass           = [];
        s_bandPass          = [];
        lowPass_filter      = [];
        bandPass_filter     = [];


        ind_plot_min_A      = []; 
        ind_plot_max_A      = []; 
        ind_plot_min_0_A    = [];
        ind_plot_max_0_A    = [];

        ind_Hplot_min_B     = [];
        ind_Hplot_max_B     = [];
        ind_Hplot_min_0_B   = [];
        ind_Hplot_max_0_B   = [];

        ind_Vplot_min_B     = [];
        ind_Vplot_max_B     = [];
        ind_Vplot_min_0_B   = [];
        ind_Vplot_max_0_B   = [];

        s_plot_center       = [];
        s_plot_amp          = [];

        ind_nu_min          = [];
        ind_nu_max          = [];

        cLim                = [];

        yellow_flag         = true;

        close_flag          = false; 

    end
    
    methods (Access = public)
        
        function mainFunction(app)
            

            L = size(app.s_ref, 2);
            
            app.ind_plot_min_A      = L/2+1;
            app.ind_plot_max_A      = L;
            
            app.ind_plot_min_0_A    = L/2+1;
            app.ind_plot_max_0_A    = L;
            
            app.ind_nu_min          = L/2+1;
            app.ind_nu_max          = L;


            L = size(app.imNav, 2);
            
            app.ind_Hplot_min_B      = 1;
            app.ind_Hplot_max_B      = L;
            
            app.ind_Hplot_min_0_B    = 1;
            app.ind_Hplot_max_0_B    = L;


            L = size(app.imNav, 1);
            
            app.ind_Vplot_min_B      = 1;
            app.ind_Vplot_max_B      = L;
            app.ind_Vplot_min_0_B    = 1;
            app.ind_Vplot_max_0_B    = L;
            
            app.s_plot_center        = ceil(L/2);
            app.s_plot_amp           = ceil(L/4);


            myRefresh_A(app);
            myRefresh_B(app);


        end
        
        function myRefresh_A(app)
            
            Fs_plot     = abs(app.Fs_ref(app.ind_plot_min_A:app.ind_plot_max_A));
            nu_plot     =     app.nu_ref(app.ind_plot_min_A:app.ind_plot_max_A);
            
            nu_mask_plot    = [];
            if ~isempty(app.bandPass_filter)
                nu_mask_plot = app.bandPass_filter(app.ind_plot_min_A:app.ind_plot_max_A);
            end
            
            
            Fs_part = abs(app.Fs_ref(app.ind_nu_min:app.ind_nu_max));
            nu_part =     app.nu_ref(app.ind_nu_min:app.ind_nu_max);
            
            hold(app.UIAxes_1, 'off')
            plot(app.UIAxes_1, nu_plot, Fs_plot, 'k.-')
            hold(app.UIAxes_1, 'on')
            plot(app.UIAxes_1, nu_part, Fs_part, 'b.-')
            
            if ~isempty(nu_mask_plot)
               plot(app.UIAxes_1, nu_plot, nu_mask_plot*max(Fs_plot(:)), 'r.-');
            end
            hold(app.UIAxes_1, 'off')
            
            xlim(app.UIAxes_1, [nu_plot(1), nu_plot(end)]  ); 
            ylim(app.UIAxes_1, [0, max(Fs_plot(:))]  ); 
             
        
        end


        function myRefresh_B(app)
            
                myMu            = app.s_plot_center;
                mySigma         = app.s_plot_amp;
            
            
                im_plot         = app.imNav(app.ind_Vplot_min_B:app.ind_Vplot_max_B, app.ind_Hplot_min_B:app.ind_Hplot_max_B);
                s_plot          = app.s_ref(1, app.ind_Hplot_min_B:app.ind_Hplot_max_B);
            
                s_filtered_plot = [];
                if ~isempty(app.s_bandPass)
                    s_filtered_plot = app.s_bandPass(1, app.ind_Hplot_min_B:app.ind_Hplot_max_B);
                end
            
                
                imagesc(app.UIAxes_2, im_plot);
                xlim(app.UIAxes_2, 'tight');
                ylim(app.UIAxes_2, 'tight');
                set(app.UIAxes_2, 'YDir', 'normal');
                colormap(app.UIAxes_2, 'gray'); 
                clim(app.UIAxes_2, app.cLim(:)');
                
                myYLim = app.UIAxes_2.YLim; 
                
                hold(app.UIAxes_2, 'on')
                if app.yellow_flag
                    plot(app.UIAxes_2, myMu + mySigma*s_plot, 'y.-');
                    app.UIAxes_2.YLim = myYLim;
                end

                if ~isempty(s_filtered_plot)
                    plot(app.UIAxes_2, myMu + mySigma*s_filtered_plot, 'b.-');
                    app.UIAxes_2.YLim = myYLim;
                end
                hold(app.UIAxes_2, 'off')
            
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, s_ref, t_ref, Fs_ref, nu_ref, imNav)


            app.s_ref   = s_ref; 
            app.t_ref   = t_ref;
            app.Fs_ref  = Fs_ref;
            app.nu_ref  = nu_ref;
            app.imNav   = imNav; 

            app.cLim    = [min(app.imNav(:)), max(app.imNav(:))];


            mainFunction(app);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            a       = app.ind_plot_min_A;
            b       = app.ind_plot_max_A;
            a_min   = app.ind_plot_min_0_A;
            
            a       = floor(  (3*a + b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_plot_min_A = a;

            myRefresh_A(app); 
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            a       = app.ind_plot_min_A;
            b       = app.ind_plot_max_A;
            a_min   = app.ind_plot_min_0_A;
            
            a       = floor(  (5*a - b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_plot_min_A = a;

            myRefresh_A(app); 
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
            a       = app.ind_plot_min_A;
            b       = app.ind_plot_max_A;
            b_max   = app.ind_plot_max_0_A;
            
            b       = ceil(  (5*b - a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_plot_max_A = b;

            myRefresh_A(app); 
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
            a       = app.ind_plot_min_A;
            b       = app.ind_plot_max_A;
            b_max   = app.ind_plot_max_0_A;
            
            b       = ceil(  (3*b + a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_plot_max_A = b;

            myRefresh_A(app); 
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            a       = floor(  (5*a - b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_a = ceil(  (d-c)/50  );
            delta_a = max(1, delta_a);
            a       = a - delta_a;
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_a = ceil(  (d-c)/500  );
            delta_a = max(1, delta_a);
            a       = a - delta_a;
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);
            
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_a = ceil(  (d-c)/500  );
            delta_a = max(1, delta_a);
            a       = a + delta_a;
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_a = ceil(  (d-c)/50  );
            delta_a = max(1, delta_a);
            a       = a + delta_a;
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            a_min   = app.ind_plot_min_0_A;
            
            a       = ceil(  (3*a + b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_nu_min = a;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;

            b       = floor(  (3*b + a)/4  );

            b       = max(a+1, b);
            b       = min(b, b_max);

            app.ind_nu_max = b;

            myRefresh_A(app);

        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_b = ceil(  (d-c)/50  );
            delta_b = max(1, delta_b);
            b       = b - delta_b;
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_nu_max = b;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_b = ceil(  (d-c)/500  );
            delta_b = max(1, delta_b);
            b       = b - delta_b;
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_nu_max = b;
            
            myRefresh_A(app);
            
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_b = ceil(  (d-c)/500  );
            delta_b = max(1, delta_b);
            b       = b + delta_b;
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_nu_max = b;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;
            
            c       = app.ind_plot_min_A;
            d       = app.ind_plot_max_A;
            
            delta_b = ceil(  (d-c)/50  );
            delta_b = max(1, delta_b);
            b       = b + delta_b;
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_nu_max = b;
            
            myRefresh_A(app);

        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            
            a       = app.ind_nu_min;
            b       = app.ind_nu_max;
            b_max   = app.ind_plot_max_0_A;
            
            b       = ceil(  (5*b - a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_nu_max = b;
            
            myRefresh_A(app);

        end

        % Button pushed function: FilterSignalButton
        function FilterSignalButtonPushed(app, event)
            
            s   = app.s_ref;

            Fs  = app.Fs_ref;
            nu  = app.nu_ref;
            
            L = size(s, 2);
            
            bump_jump_width = (app.ind_nu_max - app.ind_nu_min)/50; % ---------------------------- magic_number
            
            if (app.ind_nu_min == L/2+1) && (app.ind_nu_max == L)
                m1          = true(1, L);
                m2          = false(1, L);
                bandPass    = double(m1 - m2);
                lowPass     = double(m1);
            elseif (app.ind_nu_min == L/2+1) && (app.ind_nu_max < L)
                m1          = (   abs(nu) <= abs(  nu(1, app.ind_nu_max)  )   );
                m1          = bmDoor2Bump(m1, bump_jump_width);
                m2          = false(1, L);
                bandPass    = double(m1 - m2);
                lowPass     = double(m1);
            elseif (app.ind_nu_min > L/2+1) && (app.ind_nu_max == L)
                m1          = true(1, L);
                m2          = (   abs(nu) <  abs(  nu(1, app.ind_nu_min)  )   );
                m2          = bmDoor2Bump(m2, bump_jump_width);
                bandPass    = double(m1 - m2);
                lowPass     = double(m1);
            elseif (  app.ind_nu_min > L/2+1  ) && (  app.ind_nu_max < L  )
                m1          = (   abs(nu) <= abs(  nu(1, app.ind_nu_max)  )   );
                m1          = bmDoor2Bump(m1, bump_jump_width);
                m2          = (   abs(nu) <  abs(  nu(1, app.ind_nu_min)  )   );
                m2          = bmDoor2Bump(m2, bump_jump_width);
                bandPass    = double(m1 - m2);
                lowPass     = double(m1);
            else
                error('Case not implemented. ')
            end
            
            app.s_lowPass   = real(bmIDF(lowPass.*Fs,  nu, [], 2, 2));
            app.s_bandPass  = real(bmIDF(bandPass.*Fs, nu, [], 2, 2));
            
            
            app.lowPass_filter  = lowPass;
            app.bandPass_filter = bandPass;
            
            % figure
            % hold on
            % plot(nu, abs(Fs), '.-')
            % plot(nu, m*max(abs(Fs(:))), '.-')
            
            myRefresh_A(app);
            myRefresh_B(app);
            
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            
            a       = app.ind_Hplot_min_B;
            b       = app.ind_Hplot_max_B;
            a_min   = app.ind_Hplot_min_0_B;
            
            a       = floor(  (3*a + b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_Hplot_min_B = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            
            a       = app.ind_Hplot_min_B;
            b       = app.ind_Hplot_max_B;
            a_min   = app.ind_Hplot_min_0_B;
            
            a       = floor(  (5*a - b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_Hplot_min_B = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            a       = app.ind_Hplot_min_B;
            b       = app.ind_Hplot_max_B;
            b_max   = app.ind_Hplot_max_0_B;
            
            b       = ceil(  (5*b - a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_Hplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            
            a       = app.ind_Hplot_min_B;
            b       = app.ind_Hplot_max_B;
            b_max   = app.ind_Hplot_max_0_B;
            
            b       = ceil(  (3*b + a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_Hplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            
            a           = app.ind_Hplot_min_B;
            b           = app.ind_Hplot_max_B;
            a_min       = app.ind_Hplot_min_0_B;
            delta_ind   = b - a;
            
            a           = floor(  a - (b-a)/6  );
            a           = max(a, a_min);
            b           = a + delta_ind;
            
            app.ind_Hplot_min_B = a;
            app.ind_Hplot_max_B = b;
            
            myRefresh_B(app);


        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            
            a           = app.ind_Hplot_min_B;
            b           = app.ind_Hplot_max_B;
            b_max       = app.ind_Hplot_max_0_B;
            delta_ind   = b - a;
            
            b           = ceil(  b + (b-a)/6  );
            b           = min(b, b_max);
            a           = b - delta_ind;
            
            app.ind_Hplot_min_B = a;
            app.ind_Hplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: AMPUPButton
        function AMPUPButtonPushed(app, event)
            
            a = app.s_plot_amp;
            
            b = app.ind_Vplot_min_B;
            c = app.ind_Vplot_max_B;
            a_max = c - b;
            
            a = ceil(a*1.1);
            a = min(a, a_max);
            
            app.s_plot_amp = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: CENTERUPButton
        function CENTERUPButtonPushed(app, event)
            
            a = app.s_plot_center;
            
            b = app.ind_Vplot_min_B;
            c = app.ind_Vplot_max_B;
            d = app.ind_Vplot_max_0_B;
            
            a_max = d;
            
            a = ceil(a + (c-b)/100);
            a = min(a, a_max);
            
            app.s_plot_center = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: AMPDOWNButton
        function AMPDOWNButtonPushed(app, event)
            
            a = app.s_plot_amp;
            a_min = 0.1;
            
            a = floor(a/1.1);
            a = max(a, a_min);
            app.s_plot_amp = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: CENTERDOWNButton
        function CENTERDOWNButtonPushed(app, event)
            
            a = app.s_plot_center;
            
            b = app.ind_Vplot_min_B;
            c = app.ind_Vplot_max_B;
            a_min = 0;
            
            a = floor(a - (c-b)/100);
            a = max(a, a_min);
            
            app.s_plot_center = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: HIDEYELLOWButton
        function HIDEYELLOWButtonPushed(app, event)
            
            app.yellow_flag = false;
            
            myRefresh_B(app);

        end

        % Button pushed function: SHOWYELLOWButton
        function SHOWYELLOWButtonPushed(app, event)
            
            app.yellow_flag = true;
            
            myRefresh_B(app);

        end

        % Button pushed function: IMCONTRASTButton
        function IMCONTRASTButtonPushed(app, event)
            
            imcontrast(app.UIAxes_2);

        end

        % Button pushed function: FIXCONTRASTButton
        function FIXCONTRASTButtonPushed(app, event)
            
            app.cLim = get(app.UIAxes_2,'CLim');
            myRefresh_B(app);

        end

        % Button pushed function: STRETCH_UPButton
        function STRETCH_UPButtonPushed(app, event)
            
            a       = app.ind_Vplot_min_B;
            b       = app.ind_Vplot_max_B;
            b_max   = app.ind_Vplot_max_0_B;
            
            b       = ceil(  (3*b + a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_Vplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: deSTRETCH_UPButton
        function deSTRETCH_UPButtonPushed(app, event)
            
            a       = app.ind_Vplot_min_B;
            b       = app.ind_Vplot_max_B;
            b_max   = app.ind_Vplot_max_0_B;
            
            b       = ceil(  (5*b - a)/4  );
            
            b       = max(a+1, b);
            b       = min(b, b_max);
            
            app.ind_Vplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: SHIFTUPButton
        function SHIFTUPButtonPushed(app, event)
            
            a           = app.ind_Vplot_min_B;
            b           = app.ind_Vplot_max_B;
            b_max       = app.ind_Vplot_max_0_B;
            delta_ind   = b - a;
            
            b           = ceil(  b + (b-a)/6  );
            b           = min(b, b_max);
            a           = b - delta_ind;
            
            app.ind_Vplot_min_B = a;
            app.ind_Vplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Button pushed function: STRETCH_DOWNButton
        function STRETCH_DOWNButtonPushed(app, event)
            
            a       = app.ind_Vplot_min_B;
            b       = app.ind_Vplot_max_B;
            a_min   = app.ind_Vplot_min_0_B;
            
            a       = floor(  (3*a + b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_Vplot_min_B = a;
            
            myRefresh_B(app);

        end

        % Button pushed function: deSTRETCH_DOWNButton
        function deSTRETCH_DOWNButtonPushed(app, event)
            
            a       = app.ind_Vplot_min_B;
            b       = app.ind_Vplot_max_B;
            a_min   = app.ind_Vplot_min_0_B;
            
            a       = floor(  (5*a - b)/4  );
            
            a       = min(a, b-1);
            a       = max(a, a_min);
            
            app.ind_Vplot_min_B = a;
            
            myRefresh_B(app);
        end

        % Button pushed function: SHIFTDOWNButton
        function SHIFTDOWNButtonPushed(app, event)
            
            a           = app.ind_Vplot_min_B;
            b           = app.ind_Vplot_max_B;
            a_min       = app.ind_Vplot_min_0_B;
            delta_ind   = b - a;
            
            a           = floor(  a - (b-a)/6  );
            a           = max(a, a_min);
            b           = a + delta_ind;
            
            app.ind_Vplot_min_B = a;
            app.ind_Vplot_max_B = b;
            
            myRefresh_B(app);

        end

        % Close request function: 
        % bmMriPhi_graphical_frequency_selectorUIFigure
        function bmMriPhi_graphical_frequency_selectorUIFigureCloseRequest(app, event)
            
            app.close_flag = true; 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.bmMriPhi_graphical_frequency_selectorUIFigure = uifigure('Visible', 'off');
            app.bmMriPhi_graphical_frequency_selectorUIFigure.Name = 'bmMriPhi_graphical_frequency_selector';
            app.bmMriPhi_graphical_frequency_selectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @bmMriPhi_graphical_frequency_selectorUIFigureCloseRequest, true);
            app.UIAxes_1 = uiaxes(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            title(app.UIAxes_1, 'Magnitude Spectrum')
            xlabel(app.UIAxes_1, 'Frequency')
            ylabel(app.UIAxes_1, 'Magnitude Spectrum')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.XTickLabel = '';
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            title(app.UIAxes_2, 'Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Signal')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.Button = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<<<';
            app.Button_2 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>>>';
            app.Button_3 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<<<';
            app.Button_4 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '>>>';
            app.Button_5 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '<<<';
            app.Button_6 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '<<';
            app.Button_7 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '<';
            app.Button_8 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '>';
            app.Button_9 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '>>';
            app.Button_10 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '>>>';
            app.Button_11 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '<<<';
            app.Button_12 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '<<';
            app.Button_13 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '<';
            app.Button_14 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '>';
            app.Button_15 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '>>';
            app.Button_16 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '>>>';
            app.FilterSignalButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.FilterSignalButton.ButtonPushedFcn = createCallbackFcn(app, @FilterSignalButtonPushed, true);
            app.FilterSignalButton.Text = 'Filter Signal';
            app.Button_18 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '<<<';
            app.Button_19 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '>>>';
            app.Button_35 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '<===';
            app.Button_20 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '<<<';
            app.Button_21 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '>>>';
            app.Button_34 = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '===>';
            app.AMPUPButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.AMPUPButton.ButtonPushedFcn = createCallbackFcn(app, @AMPUPButtonPushed, true);
            app.AMPUPButton.Text = 'AMP UP';
            app.CENTERUPButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.CENTERUPButton.ButtonPushedFcn = createCallbackFcn(app, @CENTERUPButtonPushed, true);
            app.CENTERUPButton.Text = 'CENTER UP';
            app.AMPDOWNButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.AMPDOWNButton.ButtonPushedFcn = createCallbackFcn(app, @AMPDOWNButtonPushed, true);
            app.AMPDOWNButton.Text = 'AMP DOWN';
            app.CENTERDOWNButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.CENTERDOWNButton.ButtonPushedFcn = createCallbackFcn(app, @CENTERDOWNButtonPushed, true);
            app.CENTERDOWNButton.Text = 'CENTER DOWN';
            app.HIDEYELLOWButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.HIDEYELLOWButton.ButtonPushedFcn = createCallbackFcn(app, @HIDEYELLOWButtonPushed, true);
            app.HIDEYELLOWButton.Text = 'HIDE YELLOW';
            app.SHOWYELLOWButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.SHOWYELLOWButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWYELLOWButtonPushed, true);
            app.SHOWYELLOWButton.Text = 'SHOW YELLOW';
            app.STRETCH_UPButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.STRETCH_UPButton.ButtonPushedFcn = createCallbackFcn(app, @STRETCH_UPButtonPushed, true);
            app.STRETCH_UPButton.Text = 'STRETCH_UP';
            app.deSTRETCH_UPButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.deSTRETCH_UPButton.ButtonPushedFcn = createCallbackFcn(app, @deSTRETCH_UPButtonPushed, true);
            app.deSTRETCH_UPButton.Text = 'deSTRETCH_UP';
            app.SHIFTUPButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.SHIFTUPButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTUPButtonPushed, true);
            app.SHIFTUPButton.Text = 'SHIFT UP';
            app.STRETCH_DOWNButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.STRETCH_DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @STRETCH_DOWNButtonPushed, true);
            app.STRETCH_DOWNButton.Text = 'STRETCH_DOWN';
            app.deSTRETCH_DOWNButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.deSTRETCH_DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @deSTRETCH_DOWNButtonPushed, true);
            app.deSTRETCH_DOWNButton.Text = 'deSTRETCH_DOWN';
            app.SHIFTDOWNButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.SHIFTDOWNButton.ButtonPushedFcn = createCallbackFcn(app, @SHIFTDOWNButtonPushed, true);
            app.SHIFTDOWNButton.Text = 'SHIFT DOWN';
            app.IMCONTRASTButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.IMCONTRASTButton.ButtonPushedFcn = createCallbackFcn(app, @IMCONTRASTButtonPushed, true);
            app.IMCONTRASTButton.Text = 'IM CONTRAST';
            app.FIXCONTRASTButton = uibutton(app.bmMriPhi_graphical_frequency_selectorUIFigure, 'push');
            app.FIXCONTRASTButton.ButtonPushedFcn = createCallbackFcn(app, @FIXCONTRASTButtonPushed, true);
            app.FIXCONTRASTButton.Text = 'FIX CONTRAST';
            app.PlotLabel = uilabel(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            app.PlotLabel.Text = 'Plot';
            app.nu_minLabel = uilabel(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            app.nu_minLabel.Interpreter = 'tex';
            app.nu_minLabel.Text = '\nu_{min}';
            app.nu_maxLabel = uilabel(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            app.nu_maxLabel.Interpreter = 'tex';
            app.nu_maxLabel.Text = '{\nu}_{max}';
            app.XaxisLabel = uilabel(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            app.XaxisLabel.Text = 'X axis';
            app.YaxisLabel = uilabel(app.bmMriPhi_graphical_frequency_selectorUIFigure);
            app.YaxisLabel.Text = 'Y axis';
            % Show the figure after all components are created
            app.bmMriPhi_graphical_frequency_selectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bmMriPhi_graphical_frequency_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bmMriPhi_graphical_frequency_selectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bmMriPhi_graphical_frequency_selectorUIFigure)
        end
    end
end